classdef AFM_Exosome_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        DisplayChannelButtonGroup    matlab.ui.container.ButtonGroup
        HeightButton                 matlab.ui.control.RadioButton
        StiffnessButton              matlab.ui.control.RadioButton
        AdhesionButton               matlab.ui.control.RadioButton
        DeformationButton            matlab.ui.control.RadioButton
        DissipationButton            matlab.ui.control.RadioButton
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        LoadDataPanel                matlab.ui.container.Panel
        OpenFileButton               matlab.ui.control.Button
        FilePathEditFieldLabel       matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        ZeroChannelsPanel            matlab.ui.container.Panel
        ZeroHeightButton             matlab.ui.control.Button
        ZeroAdhesionButton           matlab.ui.control.Button
        PolynomialLevelingPanel      matlab.ui.container.Panel
        LevelButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ThresholdHeightPanel         matlab.ui.container.Panel
        ThresholdButton              matlab.ui.control.Button
        FilterButton                 matlab.ui.control.Button
        RemoveSmallorLargeObjectsbypixeldiameterPanel  matlab.ui.container.Panel
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SmallButton                  matlab.ui.control.RadioButton
        LargestButton                matlab.ui.control.RadioButton
        LargestEditFieldLabel        matlab.ui.control.Label
        LargestEditField             matlab.ui.control.NumericEditField
        SmallestEditFieldLabel       matlab.ui.control.Label
        SmallestEditField            matlab.ui.control.NumericEditField
        RemoveObjectsButton          matlab.ui.control.Button
        ErosionanddilationPanel      matlab.ui.container.Panel
        ErodeButton                  matlab.ui.control.Button
        StrelSizeEditFieldLabel      matlab.ui.control.Label
        StrelSizeEditField           matlab.ui.control.NumericEditField
        DilateButton                 matlab.ui.control.Button
        DeclumpingPanel              matlab.ui.container.Panel
        DeclumpObjectButton          matlab.ui.control.Button
        FindObjectCentresforeachchannelPanel  matlab.ui.container.Panel
        ObjectcentresButton          matlab.ui.control.Button
        WriteResultsPanel            matlab.ui.container.Panel
        ExcelButton                  matlab.ui.control.Button
        SQLiteButton                 matlab.ui.control.Button
        MatButton                    matlab.ui.control.Button
        OutFileEditFieldLabel        matlab.ui.control.Label
        OutFileEditField             matlab.ui.control.EditField
        SelectOutputDirectoryButton  matlab.ui.control.Button
        RestartButton                matlab.ui.control.Button
        HistoryTextAreaLabel         matlab.ui.control.Label
        HistoryTextArea              matlab.ui.control.TextArea
        DisplayResultsButtonGroup    matlab.ui.container.ButtonGroup
        HeightButton_2               matlab.ui.control.RadioButton
        StiffnessButton_2            matlab.ui.control.RadioButton
        AdhesionButton_2             matlab.ui.control.RadioButton
        DeformationButton_2          matlab.ui.control.RadioButton
        DissipationButton_2          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        pth = ''; % Path to image file
        fn = ''; % image file name
        outPth = '';
        outfn = '';
%         h1; % image handle image 1 (raw height)
%         h2; % image handle 2 (raw other channels)
%         h3; % image handle 3 (processed height)
%         h4; % image handle 4
    end
    
    
    properties (Access = public)
        h1; % image handle image 1 (raw height)
        h2; % image handle 2 (raw other channels)
        h3; % image handle 3 (processed height)
        h4; % image handle 4        
        pt = 0; % place holder for removing small large objects functions
        I = struct; % Image file % Description
        msPt1 = []; % store a mouse position for removing small/large objects
        results = []; 
        ExsnLocs;
        exCMap;
        
    end
    
    methods (Access = private)
        
        function getResults(app)
            if isempty(app.results)
                Inms = fieldnames(app.I); % get the channel names
                
                for ex = 1:numel(fields(app.I))
                    
                    app.I.(Inms{ex}).mean = zeros(app.ExsnLocs.NumObjects, 1);
                    app.I.(Inms{ex}).median = zeros(app.ExsnLocs.NumObjects, 1);
                    
                end
                
                % Set up empty arryas to store results
                 app.I.Height.diameter = zeros(app.ExsnLocs.NumObjects, 1);
                 app.I.Height.PixArea = zeros(app.ExsnLocs.NumObjects, 1);
              
                
                for dx = 1:app.ExsnLocs.NumObjects
               
                    
                    tI = zeros(size(app.I.Height.data));
                    tI(app.ExsnLocs.PixelIdxList{dx}) = 1;
                    ed = regionprops(tI, 'EquivDiameter');
                    
                    
                    if ~isempty(app.ExsnLocs.PixelIdxListErode{dx})
                        
                        app.I.Height.diameter(dx) = app.I.Height.xreal*ed.EquivDiameter/double(app.I.Height.xres);
                        app.I.Height.PixArea(dx) = numel(app.ExsnLocs.PixelIdxListErode{dx});
                        
                        for ex = 1:numel(fields(app.I))
                            
                            app.I.(Inms{ex}).mean(dx) = mean(app.I.(Inms{ex}).data(app.ExsnLocs.PixelIdxListErode{dx}));
                            app.I.(Inms{ex}).median(dx) = median(app.I.(Inms{ex}).data(app.ExsnLocs.PixelIdxListErode{dx}));
                        end
                        
                        
                    else
                        for ex = 1:numel(fields(app.I))
                            app.I.Height.diameter(dx) = nan;
                             app.I.Height.PixArea(dx) = nan;
                            app.I.(Inms{ex}).mean(dx) = nan;
                            app.I.(Inms{ex}).median(dx) = nan;
                            
                        end
%                         
                    end
                end

                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
        
            [app.fn, app.pth] = uigetfile([app.FilePathEditField.Value, '\*.*']);
            app.outPth = app.pth;
            s = strsplit(app.fn, '.');
            app.outfn = [s{1}, 'Results'];
            
            disp(app.FilePathEditField.Value)
            % try and load the Height channel
            try
                app.I.Height = readgwychannel([app.pth, app.fn], 0);
                %app.I.HeightRaw = app.I.Height;
                app.ZeroHeightButton.Enable = true;
                app.ZeroAdhesionButton.Enable = true;
                app.LevelButton.Enable = true;
                app.ThresholdButton.Enable = true;
                app.FilterButton.Enable = true;
                app.HeightButton.Enable = true;
                
                
                app.FilePathEditField.Value = [app.pth, app.fn];

                app.h1 = imagesc(app.UIAxes, app.I.Height.data); drawnow; %hold on;
                app.UIAxes.XAxis.Limits = app.h1.XData;
                app.UIAxes.YAxis.Limits = app.h1.YData;
                app.h1.ButtonDownFcn = {@im_ButtonDownFcn, app};
                
                  app.h2 = imagesc(app.UIAxes_2, app.I.Height.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;              
                
            catch ME
                disp(ME)
                disp("Height channel not found")
            end
            % try and load the stiffness channel
            try
                app.I.Stiffness = readgwychannel([app.pth, app.fn], 2);
                app.StiffnessButton.Enable = true;
            catch
                disp("Stiffness channel not found")                
            end
            % try and load the Adhesion channel
            try
                app.I.Adhesion = readgwychannel([app.pth, app.fn], 4);
                app.AdhesionButton.Enable = true;
            catch
                disp("Adhesion channel not found")
            end
            
            % try and load the Deformation channel
            try
                app.I.Deformation = readgwychannel([app.pth, app.fn], 5);
                app.DeformationButton.Enable = true;
            catch
                disp("Deformation channel not found")
            end
            
            % try and load the Dissapation channel
            try
                app.I.Dissipation = readgwychannel([app.pth, app.fn], 6);
                app.DissipationButton.Enable = true;
            catch
                disp("Dissipation channel not found")
            end 
        end

        % Button pushed function: ZeroHeightButton
        function ZeroHeightButtonPushed(app, event)
             app.I.Height.data = app.I.Height.data - (min(app.I.Height.data(:)));
             app.HistoryTextArea.Value = [app.HistoryTextArea.Value; 'Height zeroed']; 
% 
        end

        % Button pushed function: ZeroAdhesionButton
        function ZeroAdhesionButtonPushed(app, event)
            app.I.Adhesion.data = app.I.Adhesion.data - (min(app.I.Adhesion.data(:)));
            app.HistoryTextArea.Value = [app.HistoryTextArea.Value; 'Adhesion zeroed'];
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            thres = multithresh(app.I.Height.data./max(app.I.Height.data(:)), 1);
            app.I.Height.OtThrsData = app.I.Height.data;
            app.I.Height.OtThrsData(app.I.Height.OtThrsData < thres(1)*max(app.I.Height.OtThrsData(:))) = 0;
                app.h4 = imagesc(app.UIAxes_4, app.I.Height.OtThrsData); drawnow;
                app.UIAxes_4.XAxis.Limits = app.h4.XData;
                app.UIAxes_4.YAxis.Limits = app.h4.YData;
                
%                 app.SmallestObjectsButton.Enable = true;
%                 app.LargestObjectsButton.Enable = true;
                
                app.I.Height.bw = imbinarize(app.I.Height.OtThrsData);
                app.ErodeButton.Enable = true;
                app.DilateButton.Enable = true;
                app.DeclumpObjectButton.Enable = true;
                app.ObjectcentresButton.Enable = true;
%                 figure(43), imagesc(app.I.Height.bw)
                app.ExsnLocs = bwconncomp(app.I.Height.bw);
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
        app.I.Height.data = medfilt2(app.I.Height.data);
            ThresholdButtonPushed(app, event)
        end

        % Callback function
        function SmallObjectsButtonPushed(app, event)
            
            %[x, y] = ginput(2)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: RemoveObjectsButton
        function RemoveObjectsButtonPushed(app, event)
        
            if app.SmallestEditField.Value < app.LargestEditField.Value
                
                app.I.Height.bw = imbinarize(app.I.Height.OtThrsData);
                app.I.Height.bw = bwpropfilt(app.I.Height.bw, 'MajorAxisLength', [app.SmallestEditField.Value, app.LargestEditField.Value]);
                
            else
                app.RemoveObjectsButton.enable = false;
            end
            
            app.h4 = imagesc(app.UIAxes_4, app.I.Height.OtThrsData.*app.I.Height.bw); drawnow;
            app.ExsnLocs = bwconncomp(app.I.Height.bw);
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            app.I.Height.bw = imerode(app.I.Height.bw,strel('disk', app.StrelSizeEditField.Value));
            app.h4 = imagesc(app.UIAxes_4, app.I.Height.data.*app.I.Height.bw); drawnow;
            app.ExsnLocs = bwconncomp(app.I.Height.bw);
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            app.I.Height.bw = imdilate(app.I.Height.bw,strel('disk', app.StrelSizeEditField.Value));
            app.h4 = imagesc(app.UIAxes_4, app.I.Height.data.*app.I.Height.bw); drawnow;
            app.ExsnLocs = bwconncomp(app.I.Height.bw);
        end

        % Value changed function: LargestEditField
        function LargestEditFieldValueChanged(app, event)
             value = app.LargestEditField.Value;
            if app.LargestEditField.Value > app.SmallestEditField.Value
                app.RemoveObjectsButton.Enable = true;
            else
                app.RemoveObjectsButton.Enable = false;
                end
        end

        % Value changed function: SmallestEditField
        function SmallestEditFieldValueChanged(app, event)
            if app.LargestEditField.Value > app.SmallestEditField.Value
                app.RemoveObjectsButton.Enable = true;
            else
                app.RemoveObjectsButton.Enable = false;
            end
            
        end

        % Button pushed function: DeclumpObjectButton
        function DeclumpObjectButtonPushed(app, event)
            app.h4.ButtonDownFcn = {@declump_ButtonDownFcn, app};
            app.UIAxes_4.Title.String = 'Select Object to declump';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;


        end

        % Selection changed function: DisplayChannelButtonGroup
        function DisplayChannelButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayChannelButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Stiffness')
                app.h2 = imagesc(app.UIAxes_2, app.I.Stiffness.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;
            elseif strcmp(selectedButton.Text, 'Adhesion')
                app.h2 = imagesc(app.UIAxes_2, app.I.Adhesion.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;
            elseif strcmp(selectedButton.Text, 'Dissipation')
                app.h2 = imagesc(app.UIAxes_2, app.I.Dissipation.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;
            elseif strcmp(selectedButton.Text, 'Deformation')
                app.h2 = imagesc(app.UIAxes_2, app.I.Deformation.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;
            elseif strcmp(selectedButton.Text, 'Height')
                app.h2 = imagesc(app.UIAxes_2, app.I.Height.data); drawnow; %hold on;
                app.UIAxes_2.XAxis.Limits = app.h2.XData;
                app.UIAxes_2.YAxis.Limits = app.h2.YData;                
            end
        end

        % Button pushed function: LevelButton
        function LevelButtonPushed(app, event)
            app.HistoryTextArea.Value = [app.HistoryTextArea.Value; 'Levelling not implemented yet, sorry!'];
        end

        % Button pushed function: ObjectcentresButton
        function ObjectcentresButtonPushed(app, event)
            
            app.ExsnLocs.PixelIdxListErode = cell(1, app.ExsnLocs.NumObjects);
            
            %%
            
            for dx = 1:app.ExsnLocs.NumObjects
                
                tPlt = zeros(size(app.I.Height.data));
                tPlt(app.ExsnLocs.PixelIdxList{dx}) = 1;
                minAxLn = regionprops(logical(tPlt), 'MinorAxisLength');
                
                seF = floor(minAxLn(1).MinorAxisLength/3);
                if seF > 5
                    seF = 5;
                end
                
                if seF < 1
                    seF = 1;
                end
                se = strel('disk', seF);
                %     figure(1000), pcolor(tPlt); shading flat ; hold on
                
                tPlt = imerode(tPlt, se);
                app.ExsnLocs.PixelIdxListErode{dx} = find(tPlt == 1);
                %     figure(1000), pcolor(tPlt + tPlt2); shading flat; hold on
                
            end
            
            %%
            % Plot final result
            app.exCMap = zeros(size(app.I.Height.data));
            for dx = 1:app.ExsnLocs.NumObjects
                
                app.exCMap(app.ExsnLocs.PixelIdxList{dx}) = dx + 5;
                app.exCMap(app.ExsnLocs.PixelIdxListErode{dx}) = app.ExsnLocs.NumObjects + 10;
                
            end
            app.I.Height.ErodeMsk = zeros(size(app.I.Height.data));
            app.I.Height.ErodeMskOutLine =zeros(size(app.I.Height.data));
            
             app.I.Height.ErodeMsk(vertcat(app.ExsnLocs.PixelIdxListErode{:})) = 1;
             app.I.Height.ErodeMskBnd = bwboundaries(app.I.Height.ErodeMsk);
%             x = vertcat(x{:});
%             x = sub2ind(size(app.I.Height.data), x(:, 1), x(:, 2));
%             app.I.Height.ErodeMskOutLine(x) = 1;
            
%             figure(16); imagesc(app.I.Height.ErodeMsk);
%             figure(17); imagesc(app.I.Height.ErodeMskOutLine)
 
            app.h3 = imagesc(app.UIAxes_3, app.exCMap); drawnow; %hold on;
            app.UIAxes_3.XAxis.Limits = app.h3.XData;
            app.UIAxes_3.YAxis.Limits = app.h3.YData;
            s  = regionprops(app.ExsnLocs,'centroid');

            for dx = 1:app.ExsnLocs.NumObjects
                
%                  app.I.xPos(dx) = s(dx).Centroid(1);
%                  app.I.yPos(dx) = s(dx).Centroid(2);
                tt = text(app.UIAxes_3, s(dx).Centroid(1),s(dx).Centroid(2),  [num2str(dx)] );
                tt.Color = [1, 0, 0]; tt.FontSize = 14;
            end
            %figure(2), imagesc(app.exCMap);
            
            % enable the final results buttons
            app.HeightButton_2.Enable = true;
            try
            app.I.Adhesion

                app.AdhesionButton_2.Enable = true;
            catch
                disp('Adhesion channel does not exist');
            end

            try
            app.I.Stiffness

                app.StiffnessButton_2.Enable = true;
            catch
                disp('Stiffness channel does not exist');
            end

            try
            app.I.Dissipation

                app.DissipationButton_2.Enable = true;
            catch
                disp('Disapation channel does not exist');
            end
            try
            app.I.Deformation

                app.DeformationButton_2.Enable = true;
            catch
                disp('Deformation channel does not exist');
            end
            
            app.ExcelButton.Enable = true;
            app.MatButton.Enable = true;
            app.SQLiteButton.Enable = true;

        end

        % Selection changed function: DisplayResultsButtonGroup
        function DisplayResultsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayResultsButtonGroup.SelectedObject;
            hold(app.UIAxes_3, 'off')
            if strcmp(selectedButton.Text, 'Stiffness')
                
                app.h3 = imagesc(app.UIAxes_3, app.I.Stiffness.data); drawnow; %hold on;
                
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
            elseif strcmp(selectedButton.Text, 'Adhesion')
                app.h3 = imagesc(app.UIAxes_3, app.I.Adhesion.data); drawnow; %hold on;
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
            elseif strcmp(selectedButton.Text, 'Dissipation')
                app.h3 = imagesc(app.UIAxes_3, app.I.Dissipation.data); drawnow; %hold on;
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
            elseif strcmp(selectedButton.Text, 'Deformation')
                app.h3 = imagesc(app.UIAxes_3, app.I.Deformation.data); drawnow; %hold on;
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
            elseif strcmp(selectedButton.Text, 'Height')
%                 app.h3 = imagesc(app.UIAxes_3, app.I.Height.data); drawnow; %hold on;
                app.h3 = imagesc(app.UIAxes_3, app.exCMap); drawnow;
                app.UIAxes_3.XAxis.Limits = app.h3.XData;
                app.UIAxes_3.YAxis.Limits = app.h3.YData;
            end
            
            hold(app.UIAxes_3, 'on')
            
            for k = 1:numel(app.I.Height.ErodeMskBnd)
               boundary = app.I.Height.ErodeMskBnd{k};
                 plot(app.UIAxes_3, boundary(:,2), boundary(:,1), 'w', 'LineWidth', 1)
                
           
            end
            
        end

        % Button pushed function: ExcelButton
        function ExcelButtonPushed(app, event)
            getResults(app);
            Inms = fieldnames(app.I); % get the channel names
            % Make a table of the results
            g = table;
            
            for dx = 1:numel(fields(app.I))
                
                g.([Inms{dx}, '_Mean']) =  (app.I.(Inms{dx}).mean);
                g.([Inms{dx}, '_Median']) =  (app.I.(Inms{dx}).median);
            end
            
            writetable(g, [app.outPth, app.outfn, '.csv']);
            
             h = figure;
             h.Visible = 'off';
            imagesc(app.exCMap);  hold on;
            
            s  = regionprops(app.ExsnLocs,'centroid');
            
            for dx = 1:app.ExsnLocs.NumObjects
                
                %                  app.I.xPos(dx) = s(dx).Centroid(1);
                %                  app.I.yPos(dx) = s(dx).Centroid(2);
                tt = text(s(dx).Centroid(1),s(dx).Centroid(2),  [num2str(dx)] );
                tt.Color = [1, 0, 0]; tt.FontSize = 14;
            end
            
            savefig(h, [app.outPth, app.outfn, '.fig']);
            saveas(h, [app.outPth, app.outfn, '.png']);
            delete(h);
            
        end

        % Button pushed function: MatButton
        function MatButtonPushed(app, event)
            getResults(app);
            g = app.I;
            save([app.outPth, app.outfn, '.mat'], 'g');
            
            h = figure;
             h.Visible = 'off';
            imagesc(app.exCMap);  hold on;
            
            s  = regionprops(app.ExsnLocs,'centroid');
            
            for dx = 1:app.ExsnLocs.NumObjects
                
                %                  app.I.xPos(dx) = s(dx).Centroid(1);
                %                  app.I.yPos(dx) = s(dx).Centroid(2);
                tt = text(s(dx).Centroid(1),s(dx).Centroid(2),  [num2str(dx)] );
                tt.Color = [1, 0, 0]; tt.FontSize = 14;
            end
            
            savefig(h, [app.outPth, app.outfn, '.fig']);
            saveas(h, [app.outPth, app.outfn, '.png']);
            delete(h);
            
        end

        % Button pushed function: SQLiteButton
        function SQLiteButtonPushed(app, event)
            getResults(app);
            
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Height Raw Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channels')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.DisplayChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayChannelButtonGroupSelectionChanged, true);
            app.DisplayChannelButtonGroup.Title = 'Display Channel';
            app.HeightButton = uiradiobutton(app.DisplayChannelButtonGroup);
            app.HeightButton.Enable = 'off';
            app.HeightButton.Text = 'Height';
            app.HeightButton.Value = true;
            app.StiffnessButton = uiradiobutton(app.DisplayChannelButtonGroup);
            app.StiffnessButton.Enable = 'off';
            app.StiffnessButton.Text = 'Stiffness';
            app.AdhesionButton = uiradiobutton(app.DisplayChannelButtonGroup);
            app.AdhesionButton.Enable = 'off';
            app.AdhesionButton.Text = 'Adhesion';
            app.DeformationButton = uiradiobutton(app.DisplayChannelButtonGroup);
            app.DeformationButton.Enable = 'off';
            app.DeformationButton.Text = 'Deformation';
            app.DissipationButton = uiradiobutton(app.DisplayChannelButtonGroup);
            app.DissipationButton.Enable = 'off';
            app.DissipationButton.Text = 'Dissipation';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Final Results')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Height Processed')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTickLabel = '';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = '1) Load Data';
            app.OpenFileButton = uibutton(app.LoadDataPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.FilePathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.ZeroChannelsPanel = uipanel(app.UIFigure);
            app.ZeroChannelsPanel.Title = '2) Zero Channels';
            app.ZeroHeightButton = uibutton(app.ZeroChannelsPanel, 'push');
            app.ZeroHeightButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroHeightButtonPushed, true);
            app.ZeroHeightButton.Enable = 'off';
            app.ZeroHeightButton.Text = 'Zero Height';
            app.ZeroAdhesionButton = uibutton(app.ZeroChannelsPanel, 'push');
            app.ZeroAdhesionButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroAdhesionButtonPushed, true);
            app.ZeroAdhesionButton.Enable = 'off';
            app.ZeroAdhesionButton.Text = 'Zero Adhesion';
            app.PolynomialLevelingPanel = uipanel(app.UIFigure);
            app.PolynomialLevelingPanel.Title = '3) Polynomial Leveling';
            app.LevelButton = uibutton(app.PolynomialLevelingPanel, 'push');
            app.LevelButton.ButtonPushedFcn = createCallbackFcn(app, @LevelButtonPushed, true);
            app.LevelButton.Enable = 'off';
            app.LevelButton.Text = 'Level';
            app.Label = uilabel(app.PolynomialLevelingPanel);
            app.Label.Text = '';
            app.ThresholdHeightPanel = uipanel(app.UIFigure);
            app.ThresholdHeightPanel.Title = '4) Threshold Height';
            app.ThresholdButton = uibutton(app.ThresholdHeightPanel, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.FilterButton = uibutton(app.ThresholdHeightPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Text = 'Filter ';
            app.RemoveSmallorLargeObjectsbypixeldiameterPanel = uipanel(app.UIFigure);
            app.RemoveSmallorLargeObjectsbypixeldiameterPanel.Title = '5) Remove Small or Large Objects by pixel diameter';
            app.ButtonGroup = uibuttongroup(app.RemoveSmallorLargeObjectsbypixeldiameterPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.SmallButton = uiradiobutton(app.ButtonGroup);
            app.SmallButton.Text = 'Small';
            app.SmallButton.Value = true;
            app.LargestButton = uiradiobutton(app.ButtonGroup);
            app.LargestButton.Text = 'Largest';
            app.LargestEditFieldLabel = uilabel(app.RemoveSmallorLargeObjectsbypixeldiameterPanel);
            app.LargestEditFieldLabel.Text = 'Largest';
            app.LargestEditField = uieditfield(app.RemoveSmallorLargeObjectsbypixeldiameterPanel, 'numeric');
            app.LargestEditField.ValueChangedFcn = createCallbackFcn(app, @LargestEditFieldValueChanged, true);
            app.SmallestEditFieldLabel = uilabel(app.RemoveSmallorLargeObjectsbypixeldiameterPanel);
            app.SmallestEditFieldLabel.Text = 'Smallest';
            app.SmallestEditField = uieditfield(app.RemoveSmallorLargeObjectsbypixeldiameterPanel, 'numeric');
            app.SmallestEditField.ValueChangedFcn = createCallbackFcn(app, @SmallestEditFieldValueChanged, true);
            app.RemoveObjectsButton = uibutton(app.RemoveSmallorLargeObjectsbypixeldiameterPanel, 'push');
            app.RemoveObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveObjectsButtonPushed, true);
            app.RemoveObjectsButton.Enable = 'off';
            app.RemoveObjectsButton.Text = 'Remove Objects';
            app.ErosionanddilationPanel = uipanel(app.UIFigure);
            app.ErosionanddilationPanel.Title = '6) Erosion and dilation';
            app.ErodeButton = uibutton(app.ErosionanddilationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Enable = 'off';
            app.ErodeButton.Text = 'Erode';
            app.StrelSizeEditFieldLabel = uilabel(app.ErosionanddilationPanel);
            app.StrelSizeEditFieldLabel.Text = 'Strel Size';
            app.StrelSizeEditField = uieditfield(app.ErosionanddilationPanel, 'numeric');
            app.DilateButton = uibutton(app.ErosionanddilationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Enable = 'off';
            app.DilateButton.Text = 'Dilate';
            app.DeclumpingPanel = uipanel(app.UIFigure);
            app.DeclumpingPanel.Title = '7) Declumping';
            app.DeclumpObjectButton = uibutton(app.DeclumpingPanel, 'push');
            app.DeclumpObjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeclumpObjectButtonPushed, true);
            app.DeclumpObjectButton.Enable = 'off';
            app.DeclumpObjectButton.Text = 'Declump Object';
            app.FindObjectCentresforeachchannelPanel = uipanel(app.UIFigure);
            app.FindObjectCentresforeachchannelPanel.Title = '8) Find Object Centres for each channel';
            app.ObjectcentresButton = uibutton(app.FindObjectCentresforeachchannelPanel, 'push');
            app.ObjectcentresButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectcentresButtonPushed, true);
            app.ObjectcentresButton.Enable = 'off';
            app.ObjectcentresButton.Text = 'Object centres';
            app.WriteResultsPanel = uipanel(app.UIFigure);
            app.WriteResultsPanel.Title = '9) Write Results';
            app.ExcelButton = uibutton(app.WriteResultsPanel, 'push');
            app.ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
            app.ExcelButton.Enable = 'off';
            app.ExcelButton.Text = 'Excel';
            app.MatButton = uibutton(app.WriteResultsPanel, 'push');
            app.MatButton.ButtonPushedFcn = createCallbackFcn(app, @MatButtonPushed, true);
            app.MatButton.Enable = 'off';
            app.MatButton.Text = 'Mat';
            app.SQLiteButton = uibutton(app.WriteResultsPanel, 'push');
            app.SQLiteButton.ButtonPushedFcn = createCallbackFcn(app, @SQLiteButtonPushed, true);
            app.SQLiteButton.Enable = 'off';
            app.SQLiteButton.Text = 'SQLite';
            app.OutFileEditFieldLabel = uilabel(app.WriteResultsPanel);
            app.OutFileEditFieldLabel.Text = 'Out File ';
            app.OutFileEditField = uieditfield(app.WriteResultsPanel, 'text');
            app.SelectOutputDirectoryButton = uibutton(app.WriteResultsPanel, 'push');
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.HistoryTextAreaLabel = uilabel(app.UIFigure);
            app.HistoryTextAreaLabel.Text = 'History';
            app.HistoryTextArea = uitextarea(app.UIFigure);
            app.DisplayResultsButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayResultsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayResultsButtonGroupSelectionChanged, true);
            app.DisplayResultsButtonGroup.Title = 'Display Results';
            app.HeightButton_2 = uiradiobutton(app.DisplayResultsButtonGroup);
            app.HeightButton_2.Enable = 'off';
            app.HeightButton_2.Text = 'Height';
            app.HeightButton_2.Value = true;
            app.StiffnessButton_2 = uiradiobutton(app.DisplayResultsButtonGroup);
            app.StiffnessButton_2.Enable = 'off';
            app.StiffnessButton_2.Text = 'Stiffness';
            app.AdhesionButton_2 = uiradiobutton(app.DisplayResultsButtonGroup);
            app.AdhesionButton_2.Enable = 'off';
            app.AdhesionButton_2.Text = 'Adhesion';
            app.DeformationButton_2 = uiradiobutton(app.DisplayResultsButtonGroup);
            app.DeformationButton_2.Enable = 'off';
            app.DeformationButton_2.Text = 'Deformation';
            app.DissipationButton_2 = uiradiobutton(app.DisplayResultsButtonGroup);
            app.DissipationButton_2.Enable = 'off';
            app.DissipationButton_2.Text = 'Dissipation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM_Exosome_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end